classdef y16maze < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        RigNameEditFieldLabel          matlab.ui.control.Label
        RigNameEditField               matlab.ui.control.EditField
        StartRunButton                 matlab.ui.control.Button
        BaitRewardsCheckBox            matlab.ui.control.CheckBox
        LoadMasksButton                matlab.ui.control.Button
        UITable                        matlab.ui.control.Table
        AddNewBlockButton              matlab.ui.control.Button
        DeleteLastBlockButton          matlab.ui.control.Button
        SaveScheduleButton             matlab.ui.control.Button
        MaskFileEditFieldLabel         matlab.ui.control.Label
        MaskFileEditField              matlab.ui.control.EditField
        SaveDirectoryEditFieldLabel    matlab.ui.control.Label
        SaveDirectoryEditField         matlab.ui.control.EditField
        LoadSettingsfromPreviousExperimentsButton  matlab.ui.control.Button
        RedrawMasksCheckBox            matlab.ui.control.CheckBox
        SaveNewMasksCheckBox           matlab.ui.control.CheckBox
        ShowfliesCheckBox              matlab.ui.control.CheckBox
        VerbosityEditFieldLabel        matlab.ui.control.Label
        VerbosityEditField             matlab.ui.control.NumericEditField
        LoadScheduleButton             matlab.ui.control.Button
        ExperimentNameEditFieldLabel   matlab.ui.control.Label
        ExperimentNameEditField        matlab.ui.control.EditField
        ExpDateTimeEditFieldLabel      matlab.ui.control.Label
        ExpDateTimeEditField           matlab.ui.control.EditField
        Odor1Panel                     matlab.ui.container.Panel
        OptoIntensity1SliderLabel      matlab.ui.control.Label
        OptoIntensity1Slider           matlab.ui.control.Slider
        StimulationColor1ButtonGroup   matlab.ui.container.ButtonGroup
        RedButton1                     matlab.ui.control.RadioButton
        GreenButton1                   matlab.ui.control.RadioButton
        BlueButton1                    matlab.ui.control.RadioButton
        Odor1IDEditFieldLabel          matlab.ui.control.Label
        Odor1IDEditField               matlab.ui.control.EditField
        PulseWidth1Label               matlab.ui.control.Label
        PulseWidth1EditField           matlab.ui.control.NumericEditField
        PulsePeriod1EditFieldLabel     matlab.ui.control.Label
        PulsePeriod1EditField          matlab.ui.control.NumericEditField
        PulseRepeats1EditFieldLabel    matlab.ui.control.Label
        PulseRepeats1EditField         matlab.ui.control.NumericEditField
        DelayTime1EditFieldLabel       matlab.ui.control.Label
        DelayTime1EditField            matlab.ui.control.NumericEditField
        DeadTime1EditFieldLabel        matlab.ui.control.Label
        DeadTime1EditField             matlab.ui.control.NumericEditField
        TrainRepeats1EditFieldLabel    matlab.ui.control.Label
        TrainRepeats1EditField         matlab.ui.control.NumericEditField
        Odor2Panel                     matlab.ui.container.Panel
        StimulationColor2ButtonGroup   matlab.ui.container.ButtonGroup
        RedButton2                     matlab.ui.control.RadioButton
        GreenButton2                   matlab.ui.control.RadioButton
        BlueButton2                    matlab.ui.control.RadioButton
        OptoIntensity2SliderLabel      matlab.ui.control.Label
        OptoIntensity2Slider           matlab.ui.control.Slider
        Odor2IDEditFieldLabel          matlab.ui.control.Label
        Odor2IDEditField               matlab.ui.control.EditField
        PulseWidth2EditFieldLabel      matlab.ui.control.Label
        PulseWidth2EditField           matlab.ui.control.NumericEditField
        PulsePeriod2EditFieldLabel     matlab.ui.control.Label
        PulsePeriod2EditField          matlab.ui.control.NumericEditField
        PulseRepeats2EditFieldLabel    matlab.ui.control.Label
        PulseRepeats2EditField         matlab.ui.control.NumericEditField
        DelayTime2EditFieldLabel       matlab.ui.control.Label
        DelayTime2EditField            matlab.ui.control.NumericEditField
        DeadTime2EditField_2Label      matlab.ui.control.Label
        DeadTime2EditField             matlab.ui.control.NumericEditField
        TrainRepeats2EditFieldLabel    matlab.ui.control.Label
        TrainRepeats2EditField         matlab.ui.control.NumericEditField
        AddlCommentsEditFieldLabel     matlab.ui.control.Label
        AddlCommentsEditField          matlab.ui.control.EditField
        EndRunButton                   matlab.ui.control.Button
        ArenaSetupPanel                matlab.ui.container.Panel
        Fly1GenotypeEditFieldLabel     matlab.ui.control.Label
        Fly1GenotypeEditField          matlab.ui.control.EditField
        Fly2GenotypeEditFieldLabel     matlab.ui.control.Label
        Fly2GenotypeEditField          matlab.ui.control.EditField
        Fly3GenotypeEditFieldLabel     matlab.ui.control.Label
        Fly3GenotypeEditField          matlab.ui.control.EditField
        Fly4GenotypeEditFieldLabel     matlab.ui.control.Label
        Fly4GenotypeEditField          matlab.ui.control.EditField
        Arena1CheckBox                 matlab.ui.control.CheckBox
        Arena2CheckBox                 matlab.ui.control.CheckBox
        Arena3CheckBox                 matlab.ui.control.CheckBox
        Arena4CheckBox                 matlab.ui.control.CheckBox
        Arena5CheckBox                 matlab.ui.control.CheckBox
        Arena6CheckBox                 matlab.ui.control.CheckBox
        Arena7CheckBox                 matlab.ui.control.CheckBox
        Arena8CheckBox                 matlab.ui.control.CheckBox
        Fly5GenotypeEditFieldLabel     matlab.ui.control.Label
        Fly5GenotypeEditField          matlab.ui.control.EditField
        Fly6GenotypeEditFieldLabel     matlab.ui.control.Label
        Fly6GenotypeEditField          matlab.ui.control.EditField
        Fly7GenotypeEditFieldLabel     matlab.ui.control.Label
        Fly7GenotypeEditField          matlab.ui.control.EditField
        Fly8GenotypeEditFieldLabel     matlab.ui.control.Label
        Fly8GenotypeEditField          matlab.ui.control.EditField
        Arena13CheckBox                matlab.ui.control.CheckBox
        Arena14CheckBox                matlab.ui.control.CheckBox
        Arena15CheckBox                matlab.ui.control.CheckBox
        Arena16CheckBox                matlab.ui.control.CheckBox
        Fly13GenotypeEditFieldLabel    matlab.ui.control.Label
        Fly13GenotypeEditField         matlab.ui.control.EditField
        Fly14GenotypeEditFieldLabel    matlab.ui.control.Label
        Fly14GenotypeEditField         matlab.ui.control.EditField
        Fly15GenotypeEditFieldLabel    matlab.ui.control.Label
        Fly15GenotypeEditField         matlab.ui.control.EditField
        Fly16GenotypeEditFieldLabel    matlab.ui.control.Label
        Fly16GenotypeEditField         matlab.ui.control.EditField
        Arena9CheckBox                 matlab.ui.control.CheckBox
        Arena10CheckBox                matlab.ui.control.CheckBox
        Arena11CheckBox                matlab.ui.control.CheckBox
        Arena12CheckBox                matlab.ui.control.CheckBox
        Fly9GenotypeEditFieldLabel     matlab.ui.control.Label
        Fly9GenotypeEditField          matlab.ui.control.EditField
        Fly10GenotypeEditFieldLabel    matlab.ui.control.Label
        Fly10GenotypeEditField         matlab.ui.control.EditField
        Fly11GenotypeEditFieldLabel    matlab.ui.control.Label
        Fly11GenotypeEditField         matlab.ui.control.EditField
        Fly12GenotypeEditFieldLabel    matlab.ui.control.Label
        Fly12GenotypeEditField         matlab.ui.control.EditField
        Fly6CommentsEditFieldLabel     matlab.ui.control.Label
        Fly6CommentsEditField          matlab.ui.control.EditField
        Fly8CommentsEditFieldLabel     matlab.ui.control.Label
        Fly8CommentsEditField          matlab.ui.control.EditField
        Fly14CommentsEditFieldLabel    matlab.ui.control.Label
        Fly14CommentsEditField         matlab.ui.control.EditField
        Fly16CommentsEditFieldLabel    matlab.ui.control.Label
        Fly16CommentsEditField         matlab.ui.control.EditField
        Fly5CommentsEditFieldLabel     matlab.ui.control.Label
        Fly5CommentsEditField          matlab.ui.control.EditField
        Fly7CommentsEditFieldLabel     matlab.ui.control.Label
        Fly7CommentsEditField          matlab.ui.control.EditField
        Fly13CommentsEditFieldLabel    matlab.ui.control.Label
        Fly13CommentsEditField         matlab.ui.control.EditField
        Fly15CommentsEditFieldLabel    matlab.ui.control.Label
        Fly15CommentsEditField         matlab.ui.control.EditField
        Fly2CommentsEditFieldLabel     matlab.ui.control.Label
        Fly2CommentsEditField          matlab.ui.control.EditField
        Fly4CommentsEditFieldLabel     matlab.ui.control.Label
        Fly4CommentsEditField          matlab.ui.control.EditField
        Fly10CommentsEditFieldLabel    matlab.ui.control.Label
        Fly10CommentsEditField         matlab.ui.control.EditField
        Fly12CommentsEditFieldLabel    matlab.ui.control.Label
        Fly12CommentsEditField         matlab.ui.control.EditField
        Fly1CommentsEditFieldLabel     matlab.ui.control.Label
        Fly1CommentsEditField          matlab.ui.control.EditField
        Fly3CommentsEditFieldLabel     matlab.ui.control.Label
        Fly3CommentsEditField          matlab.ui.control.EditField
        Fly9CommentsEditFieldLabel     matlab.ui.control.Label
        Fly9CommentsEditField          matlab.ui.control.EditField
        Fly11CommentsEditFieldLabel    matlab.ui.control.Label
        Fly11CommentsEditField         matlab.ui.control.EditField
        SharedGenotypeCheckBox         matlab.ui.control.CheckBox
        RefreshButton                  matlab.ui.control.Button
        BackgroundPanel                matlab.ui.container.Panel
        IRIntensitySliderLabel         matlab.ui.control.Label
        IRIntensitySlider              matlab.ui.control.Slider
        LoadButton                     matlab.ui.control.Button
        BackgroundImageEditFieldLabel  matlab.ui.control.Label
        BackgroundImageEditField       matlab.ui.control.EditField
        UsePrecalculatedCheckBox       matlab.ui.control.CheckBox
        BackgroundMovieLengthinsecondsEditFieldLabel  matlab.ui.control.Label
        BackgroundMovieLengthinsecondsEditField  matlab.ui.control.NumericEditField
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UITable.Data = [1,0,0;2,1,1];
            app.ExpDateTimeEditField.Value = datestr(now,'mm_dd_yyyy-HH_MM');
        end

        % Button pushed function: StartRunButton
        function StartRunButtonPushed(app, event)
            
            rigName = app.RigNameEditField.Value;
            activeArenas = [app.Arena1CheckBox.Value app.Arena2CheckBox.Value app.Arena3CheckBox.Value app.Arena4CheckBox.Value app.Arena5CheckBox.Value app.Arena6CheckBox.Value app.Arena7CheckBox.Value app.Arena8CheckBox.Value app.Arena9CheckBox.Value app.Arena10CheckBox.Value app.Arena11CheckBox.Value app.Arena12CheckBox.Value app.Arena13CheckBox.Value app.Arena14CheckBox.Value app.Arena15CheckBox.Value app.Arena16CheckBox.Value];
            IRIntensity = app.IRIntensitySlider.Value;
            BackgroundMovieLength = app.BackgroundMovieLengthinsecondsEditField;
            
            % Odor 1 Stimulation Parameters
            if app.BlueButton1.Value
                RedIntensity1 = 0;
                GreenIntensity1 = 0;
                BlueIntensity1 = app.OptoIntensity1Slider.Value;
                OptoColor1 = 'BLUE';
            elseif app.RedButton1.Value
                RedIntensity1 = app.OptoIntensity1Slider.Value;
                GreenIntensity1 = 0;
                BlueIntensity1 = 0;
                OptoColor1 = 'RED';
            elseif app.GreenButton1.Value
                RedIntensity1 = 0;
                GreenIntensity1 = app.OptoIntensity1Slider.Value;
                BlueIntensity1 = 0;
                OptoColor1 = 'GREEN';
            else
                RedIntensity1 = 0;
                GreenIntensity1 = 0;
                BlueIntensity1 = 0;
                OptoColor1 = 'NONE';
            end
            PulseWidth1 = app.PulseWidth1EditField.Value;
            PulsePeriod1 = app.PulsePeriod1EditField.Value;
            PulseNumber1 = app.PulseRepeats1EditField.Value;
            Wait1 = app.DelayTime1EditField.Value;
            Off1 = app.DeadTime1EditField.Value;
            Iterations1 = app.TrainRepeats1EditField.Value;
            
            % Odor 2 Stimulation Parameters
            if app.BlueButton2.Value
                RedIntensity2 = 0;
                GreenIntensity2 = 0;
                BlueIntensity2 = app.OptoIntensity2Slider.Value;
                OptoColor2 = 'BLUE';
            elseif app.RedButton2.Value
                RedIntensity2 = app.OptoIntensity2Slider.Value;
                GreenIntensity2 = 0;
                BlueIntensity2 = 0;
                OptoColor2 = 'RED';
            elseif app.GreenButton2.Value
                RedIntensity2 = 0;
                GreenIntensity2 = app.OptoIntensity2Slider.Value;
                BlueIntensity2 = 0;
                OptoColor2 = 'GREEN';
            else
                RedIntensity2 = 0;
                GreenIntensity2 = 0;
                BlueIntensity2 = 0;
                OptoColor2 = 'NONE';
            end
            PulseWidth2 = app.PulseWidth2EditField.Value;
            PulsePeriod2 = app.PulsePeriod2EditField.Value;
            PulseNumber2 = app.PulseRepeats2EditField.Value;
            Wait2 = app.DelayTime2EditField.Value;
            Off2 = app.DeadTime2EditField.Value;
            Iterations2 = app.TrainRepeats2EditField.Value;
            
            % Mask Parameters
            redrawmasks = app.RedrawMasksCheckBox.Value;
            savemasks = app.SaveNewMasksCheckBox.Value;
            allMaskDirectory = app.MaskFileEditField.Value;
            
            usebackground = app.UsePrecalculatedCheckBox.Value;
            backgroundDirectory = app.BackgroundImageEditField.Value;
            
            % Trialwise Odor Delivery Parameters
            Odor1ID = app.Odor1IDEditField.Value;
            Odor2ID = app.Odor2IDEditField.Value;
            
            NumTrialsPerBlock = app.UITable.Data(:,1)';                      
            Odor1_RewardProbability = app.UITable.Data(:,2)';
            Odor2_RewardProbability = app.UITable.Data(:,3)';
            baited = app.BaitRewardsCheckBox.Value;
            
            % Runtime Parameters
            verbose = app.VerbosityEditField.Value;
            showflies = app.ShowfliesCheckBox.Value;
            savedir = app.SaveDirectoryEditField.Value;
            
            expname = app.ExperimentNameEditField.Value;
            fly1genotype = app.Fly1GenotypeEditField.Value;
            fly2genotype = app.Fly2GenotypeEditField.Value;
            fly3genotype = app.Fly3GenotypeEditField.Value;
            fly4genotype = app.Fly4GenotypeEditField.Value;
            fly5genotype = app.Fly5GenotypeEditField.Value;
            fly6genotype = app.Fly6GenotypeEditField.Value;
            fly7genotype = app.Fly7GenotypeEditField.Value;
            fly8genotype = app.Fly8GenotypeEditField.Value;
            fly9genotype = app.Fly9GenotypeEditField.Value;
            fly10genotype = app.Fly10GenotypeEditField.Value;
            fly11genotype = app.Fly11GenotypeEditField.Value;
            fly12genotype = app.Fly12GenotypeEditField.Value;
            fly13genotype = app.Fly13GenotypeEditField.Value;
            fly14genotype = app.Fly14GenotypeEditField.Value;
            fly15genotype = app.Fly15GenotypeEditField.Value;
            fly16genotype = app.Fly16GenotypeEditField.Value;
            
            fly1comments = app.Fly1CommentsEditField.Value;
            fly2comments = app.Fly2CommentsEditField.Value;
            fly3comments = app.Fly3CommentsEditField.Value;
            fly4comments = app.Fly4CommentsEditField.Value;
            fly5comments = app.Fly5CommentsEditField.Value;
            fly6comments = app.Fly6CommentsEditField.Value;
            fly7comments = app.Fly7CommentsEditField.Value;
            fly8comments = app.Fly8CommentsEditField.Value;
            fly9comments = app.Fly9CommentsEditField.Value;
            fly10comments = app.Fly10CommentsEditField.Value;
            fly11comments = app.Fly11CommentsEditField.Value;
            fly12comments = app.Fly12CommentsEditField.Value;
            fly13comments = app.Fly13CommentsEditField.Value;
            fly14comments = app.Fly14CommentsEditField.Value;
            fly15comments = app.Fly15CommentsEditField.Value;
            fly16comments = app.Fly16CommentsEditField.Value;
            
            exptime = app.ExpDateTimeEditField.Value;
            
            addlcomments = app.AddlCommentsEditField.Value;
            earlystop = false;
            
            save("Z:\Rishika\4Y-Maze\Y4ArenaSettings.mat", "rigName", 'activeArenas',"IRIntensity", "RedIntensity1", "GreenIntensity1", "BlueIntensity1", "PulseWidth1", "PulsePeriod1", "PulseNumber1", "Wait1", "Off1", "Iterations1", "OptoColor1", "RedIntensity2", "GreenIntensity2", "BlueIntensity2", "PulseWidth2", "PulsePeriod2", "PulseNumber2", "Wait2", "Off2", "Iterations2", "OptoColor2", "redrawmasks", "savemasks", "allMaskDirectory", "Odor1ID", "Odor2ID", "NumTrialsPerBlock", "Odor1_RewardProbability", "Odor2_RewardProbability", "baited", "verbose", "showflies", "savedir", "expname", "fly1genotype", "fly2genotype", "fly3genotype", "fly4genotype", "exptime",'addlcomments');
            save('Z:\Rishika\4Y-Maze\Y4ArenaState.mat','earlystop');
            
            !"C:\Program Files\MATLAB\R2020b\bin\matlab.exe" -r "Y4ArenaSetupAndRun"
        end

        % Button pushed function: LoadMasksButton
        function LoadMasksButtonPushed(app, event)
            [file,path] = uigetfile('*.mat');
            if file ~= 0
                app.MaskFileEditField.Value = fullfile(path,file);
            end
        end

        % Button pushed function: AddNewBlockButton
        function AddNewBlockButtonPushed(app, event)
            app.UITable.Data = [app.UITable.Data; 0 0 0];
        end

        % Button pushed function: DeleteLastBlockButton
        function DeleteLastBlockButtonPushed(app, event)
            app.UITable.Data = app.UITable.Data(1:end-1,:);
        end

        % Button pushed function: SaveScheduleButton
        function SaveScheduleButtonPushed(app, event)
            [file,path] = uiputfile('*.csv');
            if file ~= 0
                writematrix(app.UITable.Data,fullfile(path,file))
            end
        end

        % Button pushed function: LoadScheduleButton
        function LoadScheduleButtonPushed(app, event)
            [file,path] = uigetfile('*.csv');
            if file ~= 0
                app.UITable.Data = readmatrix(fullfile(path,file));
            end
        end

        % Value changed function: SharedGenotypeCheckBox
        function SharedGenotypeCheckBoxValueChanged(app, event)
            value = app.SharedGenotypeCheckBox.Value;
            if value
                app.Fly1GenotypeEditFieldLabel.Text = 'Genotype';
                tmp = app.Fly1GenotypeEditField.Value;
                if app.Arena2CheckBox.Value
                    app.Fly2GenotypeEditField.Value = tmp;
                    app.Fly2GenotypeEditField.Editable = false;
                end
                if app.Arena3CheckBox.Value
                    app.Fly3GenotypeEditField.Value = tmp;
                    app.Fly3GenotypeEditField.Editable = false;
                end
                if app.Arena4CheckBox.Value
                    app.Fly4GenotypeEditField.Value = tmp;
                    app.Fly4GenotypeEditField.Editable = false;
                end
                if app.Arena5CheckBox.Value
                    app.Fly5GenotypeEditField.Value = tmp;
                    app.Fly5GenotypeEditField.Editable = false;
                end
                if app.Arena6CheckBox.Value
                    app.Fly6GenotypeEditField.Value = tmp;
                    app.Fly6GenotypeEditField.Editable = false;
                end
                if app.Arena7CheckBox.Value
                    app.Fly7GenotypeEditField.Value = tmp;
                    app.Fly7GenotypeEditField.Editable = false;
                end
                if app.Arena8CheckBox.Value
                    app.Fly8GenotypeEditField.Value = tmp;
                    app.Fly8GenotypeEditField.Editable = false;
                end
                if app.Arena9CheckBox.Value
                    app.Fly9GenotypeEditField.Value = tmp;
                    app.Fly9GenotypeEditField.Editable = false;
                end
                if app.Arena10CheckBox.Value
                    app.Fly10GenotypeEditField.Value = tmp;
                    app.Fly10GenotypeEditField.Editable = false;
                end
                if app.Arena11CheckBox.Value
                    app.Fly11GenotypeEditField.Value = tmp;
                    app.Fly11GenotypeEditField.Editable = false;
                end
                if app.Arena12CheckBox.Value
                    app.Fly12GenotypeEditField.Value = tmp;
                    app.Fly12GenotypeEditField.Editable = false;
                end
            else
                app.Fly1GenotypeEditFieldLabel.Text = 'Fly1 Genotype';
                if app.Arena2CheckBox.Value
                    app.Fly2GenotypeEditField.Editable = true;
                end
                if app.Arena3CheckBox.Value
                    app.Fly3GenotypeEditField.Editable = true;
                end
                if app.Arena4CheckBox.Value
                    app.Fly4GenotypeEditField.Editable = true;
                end
                if app.Arena5CheckBox.Value
                    app.Fly5GenotypeEditField.Editable = true;
                end
                if app.Arena6CheckBox.Value
                    app.Fly6GenotypeEditField.Editable = true;
                end
                if app.Arena7CheckBox.Value
                    app.Fly7GenotypeEditField.Editable = true;
                end
                if app.Arena8CheckBox.Value
                    app.Fly8GenotypeEditField.Editable = true;
                end
                if app.Arena9CheckBox.Value
                    app.Fly9GenotypeEditField.Editable = true;
                end
                if app.Arena10CheckBox.Value
                    app.Fly10GenotypeEditField.Editable = true;
                end
                if app.Arena11CheckBox.Value
                    app.Fly11GenotypeEditField.Editable = true;
                end
                if app.Arena12CheckBox.Value
                    app.Fly12GenotypeEditField.Editable = true;
                end
                if app.Arena13CheckBox.Value
                    app.Fly13GenotypeEditField.Editable = true;
                end
                if app.Arena14CheckBox.Value
                    app.Fly14GenotypeEditField.Editable = true;
                end
                if app.Arena15CheckBox.Value
                    app.Fly15GenotypeEditField.Editable = true;
                end
                if app.Arena16CheckBox.Value
                    app.Fly16GenotypeEditField.Editable = true;
                end
            end
        end

        % Value changed function: Fly1GenotypeEditField
        function Fly1GenotypeEditFieldValueChanged(app, event)
            value = app.Fly1GenotypeEditField.Value;
            if app.SharedGenotypeCheckBox.Value
                if app.Arena2CheckBox.Value
                    app.Fly2GenotypeEditField.Value = value;
                end
                if app.Arena3CheckBox.Value
                    app.Fly3GenotypeEditField.Value = value;
                end
                if app.Arena4CheckBox.Value
                    app.Fly4GenotypeEditField.Value = value;
                end
                if app.Arena5CheckBox.Value
                    app.Fly5GenotypeEditField.Value = value;
                end
                if app.Arena6CheckBox.Value
                    app.Fly6GenotypeEditField.Value = value;
                end
                if app.Arena7CheckBox.Value
                    app.Fly7GenotypeEditField.Value = value;
                end
                if app.Arena8CheckBox.Value
                    app.Fly8GenotypeEditField.Value = value;
                end
                if app.Arena9CheckBox.Value
                    app.Fly9GenotypeEditField.Value = value;
                end
                if app.Arena10CheckBox.Value
                    app.Fly10GenotypeEditField.Value = value;
                end
                if app.Arena11CheckBox.Value
                    app.Fly11GenotypeEditField.Value = value;
                end
                if app.Arena12CheckBox.Value
                    app.Fly12GenotypeEditField.Value = value;
                end
                if app.Arena13CheckBox.Value
                    app.Fly13GenotypeEditField.Value = value;
                end
                if app.Arena14CheckBox.Value
                    app.Fly14GenotypeEditField.Value = value;
                end
                if app.Arena15CheckBox.Value
                    app.Fly15GenotypeEditField.Value = value;
                end
                if app.Arena16CheckBox.Value
                    app.Fly16GenotypeEditField.Value = value;
                end
            end
        end

        % Button pushed function: 
        % LoadSettingsfromPreviousExperimentsButton
        function LoadSettingsfromPreviousExperimentsButtonPushed(app, event)
            [file,path] = uigetfile('*.mat');
            if file ~= 0
                load(fullfile(path,file), 'YArenaInfo');
                app.RigNameEditField.Value = YArenaInfo.rigName;
                app.Arena1CheckBox.Value = YArenaInfo.ActiveArenas(1);
                app.Arena2CheckBox.Value = YArenaInfo.ActiveArenas(2);
                app.Arena3CheckBox.Value = YArenaInfo.ActiveArenas(3);
                app.Arena4CheckBox.Value = YArenaInfo.ActiveArenas(4);
                app.Arena5CheckBox.Value = YArenaInfo.ActiveArenas(5);
                app.Arena6CheckBox.Value = YArenaInfo.ActiveArenas(6);
                app.Arena7CheckBox.Value = YArenaInfo.ActiveArenas(7);
                app.Arena8CheckBox.Value = YArenaInfo.ActiveArenas(8);
                app.Arena9CheckBox.Value = YArenaInfo.ActiveArenas(9);
                app.Arena10CheckBox.Value = YArenaInfo.ActiveArenas(10);
                app.Arena11CheckBox.Value = YArenaInfo.ActiveArenas(11);
                app.Arena12CheckBox.Value = YArenaInfo.ActiveArenas(12);
                app.Arena13CheckBox.Value = YArenaInfo.ActiveArenas(13);
                app.Arena14CheckBox.Value = YArenaInfo.ActiveArenas(14);
                app.Arena15CheckBox.Value = YArenaInfo.ActiveArenas(15);
                app.Arena16CheckBox.Value = YArenaInfo.ActiveArenas(16);
                app.ExperimentNameEditField.Value = YArenaInfo.ExperimentName;
                app.ExpDateTimeEditField.Value = YArenaInfo.ExperimentDateTime;
                app.SharedGenotypeCheckBox.Value = false;
                app.Fly1GenotypeEditField.Value = YArenaInfo.Fly1;
                app.Fly2GenotypeEditField.Value = YArenaInfo.Fly2;
                app.Fly3GenotypeEditField.Value = YArenaInfo.Fly3;
                app.Fly4GenotypeEditField.Value = YArenaInfo.Fly4;
                app.Fly5GenotypeEditField.Value = YArenaInfo.Fly5;
                app.Fly6GenotypeEditField.Value = YArenaInfo.Fly6;
                app.Fly7GenotypeEditField.Value = YArenaInfo.Fly7;
                app.Fly8GenotypeEditField.Value = YArenaInfo.Fly8;
                app.Fly9GenotypeEditField.Value = YArenaInfo.Fly9;
                app.Fly10GenotypeEditField.Value = YArenaInfo.Fly10;
                app.Fly11GenotypeEditField.Value = YArenaInfo.Fly11;
                app.Fly12GenotypeEditField.Value = YArenaInfo.Fly12;
                app.Fly13GenotypeEditField.Value = YArenaInfo.Fly13;
                app.Fly14GenotypeEditField.Value = YArenaInfo.Fly14;
                app.Fly15GenotypeEditField.Value = YArenaInfo.Fly15;
                app.Fly16GenotypeEditField.Value = YArenaInfo.Fly16;
                app.Fly1CommentsEditField.Value = YArenaInfo.Fly1Comments;
                app.Fly2CommentsEditField.Value = YArenaInfo.Fly2Comments;
                app.Fly3CommentsEditField.Value = YArenaInfo.Fly3Comments;
                app.Fly4CommentsEditField.Value = YArenaInfo.Fly4Comments;
                app.Fly5CommentsEditField.Value = YArenaInfo.Fly5Comments;
                app.Fly6CommentsEditField.Value = YArenaInfo.Fly6Comments;
                app.Fly7CommentsEditField.Value = YArenaInfo.Fly7Comments;
                app.Fly8CommentsEditField.Value = YArenaInfo.Fly8Comments;
                app.Fly9CommentsEditField.Value = YArenaInfo.Fly9Comments;
                app.Fly10CommentsEditField.Value = YArenaInfo.Fly10Comments;
                app.Fly11CommentsEditField.Value = YArenaInfo.Fly11Comments;
                app.Fly12CommentsEditField.Value = YArenaInfo.Fly12Comments;
                app.Fly13CommentsEditField.Value = YArenaInfo.Fly13Comments;
                app.Fly14CommentsEditField.Value = YArenaInfo.Fly14Comments;
                app.Fly15CommentsEditField.Value = YArenaInfo.Fly15Comments;
                app.Fly16CommentsEditField.Value = YArenaInfo.Fly16Comments;
                app.TrainRepeats1EditField.Value = YArenaInfo.Iterations1;
                app.DeadTime1EditField.Value = YArenaInfo.Off1;
                app.DelayTime1EditField.Value = YArenaInfo.Wait1;
                app.PulsePeriod1EditField.Value = YArenaInfo.PulsePeriod1;
                app.PulseRepeats1EditField.Value = YArenaInfo.PulseNumber1;
                app.PulseWidth1EditField.Value = YArenaInfo.PulseWidth1;
                app.TrainRepeats2EditField.Value = YArenaInfo.Iterations2;
                app.DeadTime2EditField.Value = YArenaInfo.Off2;
                app.DelayTime2EditField.Value = YArenaInfo.Wait2;
                app.PulsePeriod2EditField.Value = YArenaInfo.PulsePeriod2;
                app.PulseRepeats2EditField.Value = YArenaInfo.PulseNumber2;
                app.PulseWidth2EditField.Value = YArenaInfo.PulseWidth2;
                app.SaveDirectoryEditField.Value = YArenaInfo.SaveDir;
                app.MaskFileEditField.Value = YArenaInfo.MaskFile;
                app.Odor1IDEditField.Value = YArenaInfo.Odor1ID;
                app.Odor2IDEditField.Value = YArenaInfo.Odor2ID;
                app.BaitRewardsCheckBox.Value = YArenaInfo.baited;
                if YArenaInfo.RedIntensity1 > 0
                    app.RedButton1.Value = true;
                    app.BlueButton1.Value = false;
                    app.GreenButton1.Value = false;
                elseif YArenaInfo.BlueIntensity1 > 0
                    app.RedButton1.Value = false;
                    app.BlueButton1.Value = true;
                    app.GreenButton1.Value = false;
                elseif YArenaInfo.GreenIntensity1 > 0
                    app.RedButton1.Value = false;
                    app.BlueButton1.Value = false;
                    app.GreenButton1.Value = true;
                end
                app.OptoIntensity1Slider.Value = max([YArenaInfo.RedIntensity1 YArenaInfo.BlueIntensity1 YArenaInfo.GreenIntensity1]);
                if YArenaInfo.RedIntensity2 > 0
                    app.RedButton2.Value = true;
                    app.BlueButton2.Value = false;
                    app.GreenButton2.Value = false;
                elseif YArenaInfo.BlueIntensity2 > 0
                    app.RedButton2.Value = false;
                    app.BlueButton2.Value = true;
                    app.GreenButton2.Value = false;
                elseif YArenaInfo.GreenIntensity2 > 0
                    app.RedButton2.Value = false;
                    app.BlueButton2.Value = false;
                    app.GreenButton2.Value = true;
                end
                app.OptoIntensity2Slider.Value = max([YArenaInfo.RedIntensity2 YArenaInfo.BlueIntensity2 YArenaInfo.GreenIntensity2]);
                app.IRIntensitySlider.Value = YArenaInfo.IRIntensity;
                app.UITable.Data = [YArenaInfo.NumTrialsPerBlock; YArenaInfo.Odor1_RewardProbability; YArenaInfo.Odor2_RewardProbability]';
                app.AddlCommentsEditField.Value = YArenaInfo.AddlComments;
            end
        end

        % Value changed function: Arena1CheckBox
        function Arena1CheckBoxValueChanged(app, event)
            value = app.Arena1CheckBox.Value;
            if value == false
                if app.SharedGenotypeCheckBox.Value
                    app.Fly1GenotypeEditField.Editable = true;
                    app.Fly1CommentsEditField.Editable = true;
                else
                    app.Fly1GenotypeEditField.Value = 'EMPTY';
                    app.Fly1GenotypeEditField.Editable = false;
                    app.Fly1CommentsEditField.Editable = false;
                end
            else
                app.Fly1GenotypeEditField.Value = 'WT';
                app.Fly1GenotypeEditField.Editable = true;
                app.Fly1CommentsEditField.Editable = true;
            end
        end

        % Value changed function: Arena2CheckBox
        function Arena2CheckBoxValueChanged(app, event)
            value = app.Arena2CheckBox.Value;
            if value == false
                app.Fly2GenotypeEditField.Value = 'EMPTY';
                app.Fly2GenotypeEditField.Editable = false;
                app.Fly2CommentsEditField.Editable = false;
            else
                if app.SharedGenotypeCheckBox.Value
                    app.Fly2GenotypeEditField.Value = app.Fly1GenotypeEditField.Value;
                    app.Fly2GenotypeEditField.Editable = false;
                    app.Fly2CommentsEditField.Editable = false;
                else
                    app.Fly2GenotypeEditField.Value = 'WT';
                    app.Fly2GenotypeEditField.Editable = true;
                    app.Fly2CommentsEditField.Editable = true;
                end
            end
        end

        % Value changed function: Arena3CheckBox
        function Arena3CheckBoxValueChanged(app, event)
            value = app.Arena3CheckBox.Value;
            if value == false
                app.Fly3GenotypeEditField.Value = 'EMPTY';
                app.Fly3GenotypeEditField.Editable = false;
            else
                if app.SharedGenotypeCheckBox.Value
                    app.Fly3GenotypeEditField.Value = app.Fly1GenotypeEditField.Value;
                    app.Fly3GenotypeEditField.Editable = false;
                else
                    app.Fly3GenotypeEditField.Value = 'WT';
                    app.Fly3GenotypeEditField.Editable = true;
                end
            end
        end

        % Value changed function: Arena4CheckBox
        function Arena4CheckBoxValueChanged(app, event)
            value = app.Arena4CheckBox.Value;
            if value == false
                app.Fly4GenotypeEditField.Value = 'EMPTY';
                app.Fly4GenotypeEditField.Editable = false;
            else
                if app.SharedGenotypeCheckBox.Value
                    app.Fly4GenotypeEditField.Value = app.Fly1GenotypeEditField.Value;
                    app.Fly4GenotypeEditField.Editable = false;
                else
                    app.Fly4GenotypeEditField.Value = 'WT';
                    app.Fly4GenotypeEditField.Editable = true;
                end
            end
        end

        % Button pushed function: EndRunButton
        function EndRunButtonPushed(app, event)
            earlystop = true;
            save('Z:\Rishika\4Y-Maze\Y4ArenaState.mat','earlystop');
        end

        % Value changed function: OptoIntensity1Slider
        function OptoIntensity1SliderValueChanged(app, event)
            value = round(app.OptoIntensity1Slider.Value/5)*5;
            app.OptoIntensity1Slider.Value = value;
        end

        % Value changed function: OptoIntensity2Slider
        function OptoIntensity2SliderValueChanged(app, event)
            value = round(app.OptoIntensity2Slider.Value/5)*5;
            app.OptoIntensity2Slider.Value = value;
        end

        % Value changed function: IRIntensitySlider
        function IRIntensitySliderValueChanged(app, event)
            value = round(app.IRIntensitySlider.Value/5)*5;
            app.IRIntensitySlider.Value = value;
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            app.ExpDateTimeEditField.Value = datestr(now,'mm_dd_yyyy-HH_MM');
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            [file,path] = uigetfile('*.mat');
            if file ~= 0
                app.BackgroundImageEditField.Value = fullfile(path,file);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.RigNameEditFieldLabel = uilabel(app.UIFigure);
            app.RigNameEditFieldLabel.Text = 'Rig Name';
            app.RigNameEditField = uieditfield(app.UIFigure, 'text');
            app.RigNameEditField.Value = 'Y16-Arena';
            app.StartRunButton = uibutton(app.UIFigure, 'push');
            app.StartRunButton.ButtonPushedFcn = createCallbackFcn(app, @StartRunButtonPushed, true);
            app.StartRunButton.Text = 'Start Run';
            app.BaitRewardsCheckBox = uicheckbox(app.UIFigure);
            app.BaitRewardsCheckBox.Text = 'Bait Rewards?';
            app.BaitRewardsCheckBox.Value = true;
            app.LoadMasksButton = uibutton(app.UIFigure, 'push');
            app.LoadMasksButton.ButtonPushedFcn = createCallbackFcn(app, @LoadMasksButtonPushed, true);
            app.LoadMasksButton.Text = 'Load Masks';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'nTrials'; 'P(Odor1)'; 'P(Odor2)'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [true true true];
            app.AddNewBlockButton = uibutton(app.UIFigure, 'push');
            app.AddNewBlockButton.ButtonPushedFcn = createCallbackFcn(app, @AddNewBlockButtonPushed, true);
            app.AddNewBlockButton.Text = 'Add New Block';
            app.DeleteLastBlockButton = uibutton(app.UIFigure, 'push');
            app.DeleteLastBlockButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteLastBlockButtonPushed, true);
            app.DeleteLastBlockButton.Text = 'Delete Last Block';
            app.SaveScheduleButton = uibutton(app.UIFigure, 'push');
            app.SaveScheduleButton.ButtonPushedFcn = createCallbackFcn(app, @SaveScheduleButtonPushed, true);
            app.SaveScheduleButton.Text = 'Save Schedule';
            app.MaskFileEditFieldLabel = uilabel(app.UIFigure);
            app.MaskFileEditFieldLabel.Text = 'Mask File:';
            app.MaskFileEditField = uieditfield(app.UIFigure, 'text');
            app.MaskFileEditField.Editable = 'off';
            app.MaskFileEditField.Value = 'Z:\Rishika\4Y-Maze\AllMasks16.mat';
            app.SaveDirectoryEditFieldLabel = uilabel(app.UIFigure);
            app.SaveDirectoryEditFieldLabel.Text = 'Save Directory:';
            app.SaveDirectoryEditField = uieditfield(app.UIFigure, 'text');
            app.SaveDirectoryEditField.Value = 'Z:\Rishika\4Y-Maze\RunData\';
            app.LoadSettingsfromPreviousExperimentsButton = uibutton(app.UIFigure, 'push');
            app.LoadSettingsfromPreviousExperimentsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSettingsfromPreviousExperimentsButtonPushed, true);
            app.LoadSettingsfromPreviousExperimentsButton.Text = 'Load Settings from Previous Experiments';
            app.RedrawMasksCheckBox = uicheckbox(app.UIFigure);
            app.RedrawMasksCheckBox.Text = 'Redraw Masks?';
            app.SaveNewMasksCheckBox = uicheckbox(app.UIFigure);
            app.SaveNewMasksCheckBox.Text = 'Save New Masks?';
            app.ShowfliesCheckBox = uicheckbox(app.UIFigure);
            app.ShowfliesCheckBox.Text = 'Show flies?';
            app.ShowfliesCheckBox.Value = true;
            app.VerbosityEditFieldLabel = uilabel(app.UIFigure);
            app.VerbosityEditFieldLabel.Text = 'Verbosity';
            app.VerbosityEditField = uieditfield(app.UIFigure, 'numeric');
            app.VerbosityEditField.Limits = [0 2];
            app.VerbosityEditField.Value = 1;
            app.LoadScheduleButton = uibutton(app.UIFigure, 'push');
            app.LoadScheduleButton.ButtonPushedFcn = createCallbackFcn(app, @LoadScheduleButtonPushed, true);
            app.LoadScheduleButton.Text = 'Load Schedule';
            app.ExperimentNameEditFieldLabel = uilabel(app.UIFigure);
            app.ExperimentNameEditFieldLabel.Text = 'Experiment Name:';
            app.ExperimentNameEditField = uieditfield(app.UIFigure, 'text');
            app.ExperimentNameEditField.Value = 'Test';
            app.ExpDateTimeEditFieldLabel = uilabel(app.UIFigure);
            app.ExpDateTimeEditFieldLabel.Text = 'Exp Date/Time';
            app.ExpDateTimeEditField = uieditfield(app.UIFigure, 'text');
            app.Odor1Panel = uipanel(app.UIFigure);
            app.Odor1Panel.Title = 'Odor 1';
            app.OptoIntensity1SliderLabel = uilabel(app.Odor1Panel);
            app.OptoIntensity1SliderLabel.Text = 'Opto Intensity 1';
            app.OptoIntensity1Slider = uislider(app.Odor1Panel);
            app.OptoIntensity1Slider.MajorTicks = [0 20 40 60 80 100];
            app.OptoIntensity1Slider.ValueChangedFcn = createCallbackFcn(app, @OptoIntensity1SliderValueChanged, true);
            app.OptoIntensity1Slider.MinorTicks = [0 5 10 15 20 25 30 35 40 45 50 55 60 65 70 75 80 85 90 95 100];
            app.OptoIntensity1Slider.Value = 25;
            app.StimulationColor1ButtonGroup = uibuttongroup(app.Odor1Panel);
            app.StimulationColor1ButtonGroup.Title = 'Stimulation Color 1';
            app.RedButton1 = uiradiobutton(app.StimulationColor1ButtonGroup);
            app.RedButton1.Text = 'Red';
            app.RedButton1.Value = true;
            app.GreenButton1 = uiradiobutton(app.StimulationColor1ButtonGroup);
            app.GreenButton1.Text = 'Green';
            app.BlueButton1 = uiradiobutton(app.StimulationColor1ButtonGroup);
            app.BlueButton1.Text = 'Blue';
            app.Odor1IDEditFieldLabel = uilabel(app.Odor1Panel);
            app.Odor1IDEditFieldLabel.Text = 'Odor 1 ID';
            app.Odor1IDEditField = uieditfield(app.Odor1Panel, 'text');
            app.Odor1IDEditField.Value = 'OCT';
            app.PulseWidth1Label = uilabel(app.Odor1Panel);
            app.PulseWidth1Label.Text = 'Pulse Width 1';
            app.PulseWidth1EditField = uieditfield(app.Odor1Panel, 'numeric');
            app.PulseWidth1EditField.Limits = [0 Inf];
            app.PulseWidth1EditField.RoundFractionalValues = 'on';
            app.PulseWidth1EditField.Value = 500;
            app.PulsePeriod1EditFieldLabel = uilabel(app.Odor1Panel);
            app.PulsePeriod1EditFieldLabel.Text = 'Pulse Period 1';
            app.PulsePeriod1EditField = uieditfield(app.Odor1Panel, 'numeric');
            app.PulsePeriod1EditField.Limits = [0 Inf];
            app.PulsePeriod1EditField.Value = 1000;
            app.PulseRepeats1EditFieldLabel = uilabel(app.Odor1Panel);
            app.PulseRepeats1EditFieldLabel.Text = 'Pulse Repeats 1';
            app.PulseRepeats1EditField = uieditfield(app.Odor1Panel, 'numeric');
            app.PulseRepeats1EditField.Limits = [0 Inf];
            app.PulseRepeats1EditField.Value = 1;
            app.DelayTime1EditFieldLabel = uilabel(app.Odor1Panel);
            app.DelayTime1EditFieldLabel.Text = 'Delay Time 1';
            app.DelayTime1EditField = uieditfield(app.Odor1Panel, 'numeric');
            app.DelayTime1EditField.Limits = [0 Inf];
            app.DeadTime1EditFieldLabel = uilabel(app.Odor1Panel);
            app.DeadTime1EditFieldLabel.Text = 'Dead Time 1';
            app.DeadTime1EditField = uieditfield(app.Odor1Panel, 'numeric');
            app.DeadTime1EditField.Limits = [0 Inf];
            app.TrainRepeats1EditFieldLabel = uilabel(app.Odor1Panel);
            app.TrainRepeats1EditFieldLabel.Text = 'Train Repeats 1';
            app.TrainRepeats1EditField = uieditfield(app.Odor1Panel, 'numeric');
            app.TrainRepeats1EditField.Limits = [0 Inf];
            app.TrainRepeats1EditField.Value = 1;
            app.Odor2Panel = uipanel(app.UIFigure);
            app.Odor2Panel.Title = 'Odor 2';
            app.StimulationColor2ButtonGroup = uibuttongroup(app.Odor2Panel);
            app.StimulationColor2ButtonGroup.Title = 'Stimulation Color 2';
            app.RedButton2 = uiradiobutton(app.StimulationColor2ButtonGroup);
            app.RedButton2.Text = 'Red';
            app.RedButton2.Value = true;
            app.GreenButton2 = uiradiobutton(app.StimulationColor2ButtonGroup);
            app.GreenButton2.Text = 'Green';
            app.BlueButton2 = uiradiobutton(app.StimulationColor2ButtonGroup);
            app.BlueButton2.Text = 'Blue';
            app.OptoIntensity2SliderLabel = uilabel(app.Odor2Panel);
            app.OptoIntensity2SliderLabel.Text = 'Opto Intensity 2';
            app.OptoIntensity2Slider = uislider(app.Odor2Panel);
            app.OptoIntensity2Slider.MajorTicks = [0 20 40 60 80 100];
            app.OptoIntensity2Slider.ValueChangedFcn = createCallbackFcn(app, @OptoIntensity2SliderValueChanged, true);
            app.OptoIntensity2Slider.MinorTicks = [0 5 10 15 20 25 30 35 40 45 50 55 60 65 70 75 80 85 90 95 100];
            app.OptoIntensity2Slider.Value = 25;
            app.Odor2IDEditFieldLabel = uilabel(app.Odor2Panel);
            app.Odor2IDEditFieldLabel.Text = 'Odor 2 ID';
            app.Odor2IDEditField = uieditfield(app.Odor2Panel, 'text');
            app.Odor2IDEditField.Value = 'MCH';
            app.PulseWidth2EditFieldLabel = uilabel(app.Odor2Panel);
            app.PulseWidth2EditFieldLabel.Text = 'Pulse Width 2';
            app.PulseWidth2EditField = uieditfield(app.Odor2Panel, 'numeric');
            app.PulseWidth2EditField.Limits = [0 Inf];
            app.PulseWidth2EditField.RoundFractionalValues = 'on';
            app.PulseWidth2EditField.Value = 500;
            app.PulsePeriod2EditFieldLabel = uilabel(app.Odor2Panel);
            app.PulsePeriod2EditFieldLabel.Text = 'Pulse Period 2';
            app.PulsePeriod2EditField = uieditfield(app.Odor2Panel, 'numeric');
            app.PulsePeriod2EditField.Limits = [0 Inf];
            app.PulsePeriod2EditField.Value = 1000;
            app.PulseRepeats2EditFieldLabel = uilabel(app.Odor2Panel);
            app.PulseRepeats2EditFieldLabel.Text = 'Pulse Repeats 2';
            app.PulseRepeats2EditField = uieditfield(app.Odor2Panel, 'numeric');
            app.PulseRepeats2EditField.Limits = [0 Inf];
            app.PulseRepeats2EditField.Value = 1;
            app.DelayTime2EditFieldLabel = uilabel(app.Odor2Panel);
            app.DelayTime2EditFieldLabel.Text = 'Delay Time 2';
            app.DelayTime2EditField = uieditfield(app.Odor2Panel, 'numeric');
            app.DelayTime2EditField.Limits = [0 Inf];
            app.DeadTime2EditField_2Label = uilabel(app.Odor2Panel);
            app.DeadTime2EditField_2Label.Text = 'Dead Time 2';
            app.DeadTime2EditField = uieditfield(app.Odor2Panel, 'numeric');
            app.DeadTime2EditField.Limits = [0 Inf];
            app.TrainRepeats2EditFieldLabel = uilabel(app.Odor2Panel);
            app.TrainRepeats2EditFieldLabel.Text = 'Train Repeats 2';
            app.TrainRepeats2EditField = uieditfield(app.Odor2Panel, 'numeric');
            app.TrainRepeats2EditField.Limits = [0 Inf];
            app.TrainRepeats2EditField.Value = 1;
            app.AddlCommentsEditFieldLabel = uilabel(app.UIFigure);
            app.AddlCommentsEditFieldLabel.Text = 'Addl Comments:';
            app.AddlCommentsEditField = uieditfield(app.UIFigure, 'text');
            app.EndRunButton = uibutton(app.UIFigure, 'push');
            app.EndRunButton.ButtonPushedFcn = createCallbackFcn(app, @EndRunButtonPushed, true);
            app.EndRunButton.Text = 'End Run';
            app.ArenaSetupPanel = uipanel(app.UIFigure);
            app.ArenaSetupPanel.Title = 'Arena Setup';
            app.Fly1GenotypeEditFieldLabel = uilabel(app.ArenaSetupPanel);
            app.Fly1GenotypeEditFieldLabel.Text = 'Fly1 Genotype';
            app.Fly1GenotypeEditField = uieditfield(app.ArenaSetupPanel, 'text');
            app.Fly1GenotypeEditField.ValueChangedFcn = createCallbackFcn(app, @Fly1GenotypeEditFieldValueChanged, true);
            app.Fly1GenotypeEditField.Value = 'WT';
            app.Fly2GenotypeEditFieldLabel = uilabel(app.ArenaSetupPanel);
            app.Fly2GenotypeEditFieldLabel.Text = 'Fly2 Genotype';
            app.Fly2GenotypeEditField = uieditfield(app.ArenaSetupPanel, 'text');
            app.Fly2GenotypeEditField.Value = 'WT';
            app.Fly3GenotypeEditFieldLabel = uilabel(app.ArenaSetupPanel);
            app.Fly3GenotypeEditFieldLabel.Text = 'Fly3 Genotype';
            app.Fly3GenotypeEditField = uieditfield(app.ArenaSetupPanel, 'text');
            app.Fly3GenotypeEditField.Value = 'WT';
            app.Fly4GenotypeEditFieldLabel = uilabel(app.ArenaSetupPanel);
            app.Fly4GenotypeEditFieldLabel.Text = 'Fly4 Genotype';
            app.Fly4GenotypeEditField = uieditfield(app.ArenaSetupPanel, 'text');
            app.Fly4GenotypeEditField.Value = 'WT';
            app.Arena1CheckBox = uicheckbox(app.ArenaSetupPanel);
            app.Arena1CheckBox.ValueChangedFcn = createCallbackFcn(app, @Arena1CheckBoxValueChanged, true);
            app.Arena1CheckBox.Text = 'Arena 1';
            app.Arena1CheckBox.Value = true;
            app.Arena2CheckBox = uicheckbox(app.ArenaSetupPanel);
            app.Arena2CheckBox.ValueChangedFcn = createCallbackFcn(app, @Arena2CheckBoxValueChanged, true);
            app.Arena2CheckBox.Text = 'Arena 2';
            app.Arena2CheckBox.Value = true;
            app.Arena3CheckBox = uicheckbox(app.ArenaSetupPanel);
            app.Arena3CheckBox.ValueChangedFcn = createCallbackFcn(app, @Arena3CheckBoxValueChanged, true);
            app.Arena3CheckBox.Text = 'Arena 3';
            app.Arena3CheckBox.Value = true;
            app.Arena4CheckBox = uicheckbox(app.ArenaSetupPanel);
            app.Arena4CheckBox.ValueChangedFcn = createCallbackFcn(app, @Arena4CheckBoxValueChanged, true);
            app.Arena4CheckBox.Text = 'Arena 4';
            app.Arena4CheckBox.Value = true;
            app.Arena5CheckBox = uicheckbox(app.ArenaSetupPanel);
            app.Arena5CheckBox.Text = 'Arena 5';
            app.Arena5CheckBox.Value = true;
            app.Arena6CheckBox = uicheckbox(app.ArenaSetupPanel);
            app.Arena6CheckBox.Text = 'Arena 6';
            app.Arena6CheckBox.Value = true;
            app.Arena7CheckBox = uicheckbox(app.ArenaSetupPanel);
            app.Arena7CheckBox.Text = 'Arena 7';
            app.Arena7CheckBox.Value = true;
            app.Arena8CheckBox = uicheckbox(app.ArenaSetupPanel);
            app.Arena8CheckBox.Text = 'Arena 8';
            app.Arena8CheckBox.Value = true;
            app.Fly5GenotypeEditFieldLabel = uilabel(app.ArenaSetupPanel);
            app.Fly5GenotypeEditFieldLabel.Text = 'Fly5 Genotype';
            app.Fly5GenotypeEditField = uieditfield(app.ArenaSetupPanel, 'text');
            app.Fly5GenotypeEditField.Value = 'WT';
            app.Fly6GenotypeEditFieldLabel = uilabel(app.ArenaSetupPanel);
            app.Fly6GenotypeEditFieldLabel.Text = 'Fly6 Genotype';
            app.Fly6GenotypeEditField = uieditfield(app.ArenaSetupPanel, 'text');
            app.Fly6GenotypeEditField.Value = 'WT';
            app.Fly7GenotypeEditFieldLabel = uilabel(app.ArenaSetupPanel);
            app.Fly7GenotypeEditFieldLabel.Text = 'Fly7 Genotype';
            app.Fly7GenotypeEditField = uieditfield(app.ArenaSetupPanel, 'text');
            app.Fly7GenotypeEditField.Value = 'WT';
            app.Fly8GenotypeEditFieldLabel = uilabel(app.ArenaSetupPanel);
            app.Fly8GenotypeEditFieldLabel.Text = 'Fly8 Genotype';
            app.Fly8GenotypeEditField = uieditfield(app.ArenaSetupPanel, 'text');
            app.Fly8GenotypeEditField.Value = 'WT';
            app.Arena13CheckBox = uicheckbox(app.ArenaSetupPanel);
            app.Arena13CheckBox.Text = 'Arena 13';
            app.Arena13CheckBox.Value = true;
            app.Arena14CheckBox = uicheckbox(app.ArenaSetupPanel);
            app.Arena14CheckBox.Text = 'Arena 14';
            app.Arena14CheckBox.Value = true;
            app.Arena15CheckBox = uicheckbox(app.ArenaSetupPanel);
            app.Arena15CheckBox.Text = 'Arena 15';
            app.Arena15CheckBox.Value = true;
            app.Arena16CheckBox = uicheckbox(app.ArenaSetupPanel);
            app.Arena16CheckBox.Text = 'Arena 16';
            app.Arena16CheckBox.Value = true;
            app.Fly13GenotypeEditFieldLabel = uilabel(app.ArenaSetupPanel);
            app.Fly13GenotypeEditFieldLabel.Text = 'Fly13 Genotype';
            app.Fly13GenotypeEditField = uieditfield(app.ArenaSetupPanel, 'text');
            app.Fly13GenotypeEditField.Value = 'WT';
            app.Fly14GenotypeEditFieldLabel = uilabel(app.ArenaSetupPanel);
            app.Fly14GenotypeEditFieldLabel.Text = 'Fly14 Genotype';
            app.Fly14GenotypeEditField = uieditfield(app.ArenaSetupPanel, 'text');
            app.Fly14GenotypeEditField.Value = 'WT';
            app.Fly15GenotypeEditFieldLabel = uilabel(app.ArenaSetupPanel);
            app.Fly15GenotypeEditFieldLabel.Text = 'Fly15 Genotype';
            app.Fly15GenotypeEditField = uieditfield(app.ArenaSetupPanel, 'text');
            app.Fly15GenotypeEditField.Value = 'WT';
            app.Fly16GenotypeEditFieldLabel = uilabel(app.ArenaSetupPanel);
            app.Fly16GenotypeEditFieldLabel.Text = 'Fly16 Genotype';
            app.Fly16GenotypeEditField = uieditfield(app.ArenaSetupPanel, 'text');
            app.Fly16GenotypeEditField.Value = 'WT';
            app.Arena9CheckBox = uicheckbox(app.ArenaSetupPanel);
            app.Arena9CheckBox.Text = 'Arena 9';
            app.Arena9CheckBox.Value = true;
            app.Arena10CheckBox = uicheckbox(app.ArenaSetupPanel);
            app.Arena10CheckBox.Text = 'Arena 10';
            app.Arena10CheckBox.Value = true;
            app.Arena11CheckBox = uicheckbox(app.ArenaSetupPanel);
            app.Arena11CheckBox.Text = 'Arena 11';
            app.Arena11CheckBox.Value = true;
            app.Arena12CheckBox = uicheckbox(app.ArenaSetupPanel);
            app.Arena12CheckBox.Text = 'Arena 12';
            app.Arena12CheckBox.Value = true;
            app.Fly9GenotypeEditFieldLabel = uilabel(app.ArenaSetupPanel);
            app.Fly9GenotypeEditFieldLabel.Text = 'Fly9 Genotype';
            app.Fly9GenotypeEditField = uieditfield(app.ArenaSetupPanel, 'text');
            app.Fly9GenotypeEditField.Value = 'WT';
            app.Fly10GenotypeEditFieldLabel = uilabel(app.ArenaSetupPanel);
            app.Fly10GenotypeEditFieldLabel.Text = 'Fly10 Genotype';
            app.Fly10GenotypeEditField = uieditfield(app.ArenaSetupPanel, 'text');
            app.Fly10GenotypeEditField.Value = 'WT';
            app.Fly11GenotypeEditFieldLabel = uilabel(app.ArenaSetupPanel);
            app.Fly11GenotypeEditFieldLabel.Text = 'Fly11 Genotype';
            app.Fly11GenotypeEditField = uieditfield(app.ArenaSetupPanel, 'text');
            app.Fly11GenotypeEditField.Value = 'WT';
            app.Fly12GenotypeEditFieldLabel = uilabel(app.ArenaSetupPanel);
            app.Fly12GenotypeEditFieldLabel.Text = 'Fly12 Genotype';
            app.Fly12GenotypeEditField = uieditfield(app.ArenaSetupPanel, 'text');
            app.Fly12GenotypeEditField.Value = 'WT';
            app.Fly6CommentsEditFieldLabel = uilabel(app.ArenaSetupPanel);
            app.Fly6CommentsEditFieldLabel.Text = 'Fly6 Comments';
            app.Fly6CommentsEditField = uieditfield(app.ArenaSetupPanel, 'text');
            app.Fly8CommentsEditFieldLabel = uilabel(app.ArenaSetupPanel);
            app.Fly8CommentsEditFieldLabel.Text = 'Fly8 Comments';
            app.Fly8CommentsEditField = uieditfield(app.ArenaSetupPanel, 'text');
            app.Fly14CommentsEditFieldLabel = uilabel(app.ArenaSetupPanel);
            app.Fly14CommentsEditFieldLabel.Text = 'Fly14 Comments';
            app.Fly14CommentsEditField = uieditfield(app.ArenaSetupPanel, 'text');
            app.Fly16CommentsEditFieldLabel = uilabel(app.ArenaSetupPanel);
            app.Fly16CommentsEditFieldLabel.Text = 'Fly16 Comments';
            app.Fly16CommentsEditField = uieditfield(app.ArenaSetupPanel, 'text');
            app.Fly5CommentsEditFieldLabel = uilabel(app.ArenaSetupPanel);
            app.Fly5CommentsEditFieldLabel.Text = 'Fly5 Comments';
            app.Fly5CommentsEditField = uieditfield(app.ArenaSetupPanel, 'text');
            app.Fly7CommentsEditFieldLabel = uilabel(app.ArenaSetupPanel);
            app.Fly7CommentsEditFieldLabel.Text = 'Fly7 Comments';
            app.Fly7CommentsEditField = uieditfield(app.ArenaSetupPanel, 'text');
            app.Fly13CommentsEditFieldLabel = uilabel(app.ArenaSetupPanel);
            app.Fly13CommentsEditFieldLabel.Text = 'Fly13 Comments';
            app.Fly13CommentsEditField = uieditfield(app.ArenaSetupPanel, 'text');
            app.Fly15CommentsEditFieldLabel = uilabel(app.ArenaSetupPanel);
            app.Fly15CommentsEditFieldLabel.Text = 'Fly15 Comments';
            app.Fly15CommentsEditField = uieditfield(app.ArenaSetupPanel, 'text');
            app.Fly2CommentsEditFieldLabel = uilabel(app.ArenaSetupPanel);
            app.Fly2CommentsEditFieldLabel.Text = 'Fly2 Comments';
            app.Fly2CommentsEditField = uieditfield(app.ArenaSetupPanel, 'text');
            app.Fly4CommentsEditFieldLabel = uilabel(app.ArenaSetupPanel);
            app.Fly4CommentsEditFieldLabel.Text = 'Fly4 Comments';
            app.Fly4CommentsEditField = uieditfield(app.ArenaSetupPanel, 'text');
            app.Fly10CommentsEditFieldLabel = uilabel(app.ArenaSetupPanel);
            app.Fly10CommentsEditFieldLabel.Text = 'Fly10 Comments';
            app.Fly10CommentsEditField = uieditfield(app.ArenaSetupPanel, 'text');
            app.Fly12CommentsEditFieldLabel = uilabel(app.ArenaSetupPanel);
            app.Fly12CommentsEditFieldLabel.Text = 'Fly12 Comments';
            app.Fly12CommentsEditField = uieditfield(app.ArenaSetupPanel, 'text');
            app.Fly1CommentsEditFieldLabel = uilabel(app.ArenaSetupPanel);
            app.Fly1CommentsEditFieldLabel.Text = 'Fly1 Comments';
            app.Fly1CommentsEditField = uieditfield(app.ArenaSetupPanel, 'text');
            app.Fly3CommentsEditFieldLabel = uilabel(app.ArenaSetupPanel);
            app.Fly3CommentsEditFieldLabel.Text = 'Fly3 Comments';
            app.Fly3CommentsEditField = uieditfield(app.ArenaSetupPanel, 'text');
            app.Fly9CommentsEditFieldLabel = uilabel(app.ArenaSetupPanel);
            app.Fly9CommentsEditFieldLabel.Text = 'Fly9 Comments';
            app.Fly9CommentsEditField = uieditfield(app.ArenaSetupPanel, 'text');
            app.Fly11CommentsEditFieldLabel = uilabel(app.ArenaSetupPanel);
            app.Fly11CommentsEditFieldLabel.Text = 'Fly11 Comments';
            app.Fly11CommentsEditField = uieditfield(app.ArenaSetupPanel, 'text');
            app.SharedGenotypeCheckBox = uicheckbox(app.ArenaSetupPanel);
            app.SharedGenotypeCheckBox.ValueChangedFcn = createCallbackFcn(app, @SharedGenotypeCheckBoxValueChanged, true);
            app.SharedGenotypeCheckBox.Text = 'Shared Genotype';
            app.RefreshButton = uibutton(app.UIFigure, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            app.BackgroundPanel = uipanel(app.UIFigure);
            app.BackgroundPanel.Title = 'Background';
            app.IRIntensitySliderLabel = uilabel(app.BackgroundPanel);
            app.IRIntensitySliderLabel.Text = 'IR Intensity';
            app.IRIntensitySlider = uislider(app.BackgroundPanel);
            app.IRIntensitySlider.MajorTicks = [0 20 40 60 80 100];
            app.IRIntensitySlider.ValueChangedFcn = createCallbackFcn(app, @IRIntensitySliderValueChanged, true);
            app.IRIntensitySlider.MinorTicks = [0 5 10 15 20 25 30 35 40 45 50 55 60 65 70 75 80 85 90 95 100];
            app.IRIntensitySlider.Value = 25;
            app.LoadButton = uibutton(app.BackgroundPanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.BackgroundImageEditFieldLabel = uilabel(app.BackgroundPanel);
            app.BackgroundImageEditFieldLabel.Text = 'Background Image:';
            app.BackgroundImageEditField = uieditfield(app.BackgroundPanel, 'text');
            app.BackgroundImageEditField.Editable = 'off';
            app.BackgroundImageEditField.Value = 'Z:\Rishika\4Y-Maze\Y16Background.mat';
            app.UsePrecalculatedCheckBox = uicheckbox(app.BackgroundPanel);
            app.UsePrecalculatedCheckBox.Text = 'Use Pre-calculated?';
            app.BackgroundMovieLengthinsecondsEditFieldLabel = uilabel(app.BackgroundPanel);
            app.BackgroundMovieLengthinsecondsEditFieldLabel.Text = 'Background Movie Length (in seconds)';
            app.BackgroundMovieLengthinsecondsEditField = uieditfield(app.BackgroundPanel, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = y16maze

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end